classdef prarob_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Zakret3EditField           matlab.ui.control.EditField
        Zakret3EditFieldLabel      matlab.ui.control.Label
        Zakret2EditField           matlab.ui.control.EditField
        Zakret2EditFieldLabel      matlab.ui.control.Label
        Zakret1EditField           matlab.ui.control.EditField
        Zakret1EditFieldLabel      matlab.ui.control.Label
        NavedenepozicijemotoradovestemagnetnaLabel  matlab.ui.control.Label
        PostavinaZEditField        matlab.ui.control.NumericEditField
        PostavinaZEditFieldLabel   matlab.ui.control.Label
        PostavinaYEditField        matlab.ui.control.NumericEditField
        PostavinaYEditFieldLabel   matlab.ui.control.Label
        PostavinaXEditField        matlab.ui.control.NumericEditField
        PostavinaXEditFieldLabel   matlab.ui.control.Label
        ZTextArea                  matlab.ui.control.TextArea
        ZTextAreaLabel             matlab.ui.control.Label
        YTextArea                  matlab.ui.control.TextArea
        YTextAreaLabel             matlab.ui.control.Label
        XTextArea                  matlab.ui.control.TextArea
        XTextAreaLabel             matlab.ui.control.Label
        Trenutnapozicijamotora3EditField  matlab.ui.control.NumericEditField
        Trenutnapozicijamotora3EditFieldLabel  matlab.ui.control.Label
        Trenutnapozicijamotora2EditField  matlab.ui.control.NumericEditField
        Trenutnapozicijamotora2EditFieldLabel  matlab.ui.control.Label
        pp                         matlab.ui.control.NumericEditField
        Trenutnapozicijamotora1EditFieldLabel  matlab.ui.control.Label
        Motor3positionSlider       matlab.ui.control.Slider
        Motor3positionSliderLabel  matlab.ui.control.Label
        Motor2positionSlider       matlab.ui.control.Slider
        Motor2positionSliderLabel  matlab.ui.control.Label
        Motor1positionSlider       matlab.ui.control.Slider
        Motor1positionSliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        SimulinkModelName; % Description
        motor1_position; % Description
        motor2_position; % Description
        motor3_position; % Description

        invX;
        invY;
        invZ;
        invp1;
        invp2;
        invp3;


        krak1;
        krak2;
        krak3;
        trenutni_x;
        trenutni_y;
        trenutni_z;
    end
    
    methods (Access = public)

        function results = stupnjeviMotora1Callback(app, value)
            global hApp;
            value0 = hApp.Zakret1EditField.Value
            hApp.Zakret1EditField.Value = mat2str(round(value,2))
            drawnow
        end

        function results = stupnjeviMotora2Callback(app, value)
            global hApp;
            value0 = hApp.Zakret2EditField.Value
            hApp.Zakret2EditField.Value = mat2str(round(value,2))
            drawnow; 
        end
        
        function results = stupnjeviMotora3Callback(app, value)
            global hApp;
            value0 = hApp.Zakret3EditField.Value
            hApp.Zakret3EditField.Value = mat2str(round(value,2))
            drawnow; 
        end


        function xyz = direktna_kinematika(app); 
            p0=app.motor1_position;
            p1=app.motor2_position;
            p2=app.motor3_position;
            
            a1=app.krak1;
            a2=app.krak2;
            a3=app.krak3;

            T2T = [-sin(p2) 0 cos(p2) a3*cos(p2); cos(p2) 0 sin(p2) a3*sin(p2); 0 1 0 0; 0 0 0 1];
            T12 = [cos(p1) -sin(p1) 0 a2*cos(p1); sin(p1) cos(p1) 0 a2*sin(p1); 0 0 1 0; 0 0 0 1];
            T01 = [0 cos(p0) -sin(p0) 0; 0 sin(p0) cos(p0) 0; 1 0 0 a1; 0 0 0 1];

            T0T = T01*T12*T2T;
            xyz = T0T([1:3],4);

            app.trenutni_x = xyz(1);
            app.trenutni_y = xyz(2);
            app.trenutni_z = xyz(3);

            update_current_position(app);
        end
        
        function results = update_current_position(app);
            app.XTextArea.Value=mat2str(round(app.trenutni_x,3));
            app.YTextArea.Value=mat2str(round(app.trenutni_y,3));
            app.ZTextArea.Value=mat2str(round(app.trenutni_z,3));
                
        end
        
        function results = inverzna_kinematika(app);
            app.invX = app.PostavinaXEditField.Value;
            app.invY = app.PostavinaYEditField.Value;
            app.invZ = app.PostavinaZEditField.Value;

            x=app.invX;
            y=app.invY;
            z=app.invZ;

            a1=app.krak1;
            a2=app.krak2;
            a3=app.krak3;

            q1 = atan2(y, x);
            r1 = sqrt(x^2+y^2);
            r2 = z - a1;
            fi2 = atan2(r2, r1);
            r3 = sqrt(r2^2+r1^2);
            fi1 = acos((a3^2 - a2^2 - r3^2)/(-2*a2*r3));
            q2 = pi/2 - (fi1+fi2);
            fi3 = acos((r3^2-a2^2 -a3^2)/(-2*a2*a3));
            q3=pi-fi3;


            p = [q1, q2, q3];
            pd = [rad2deg(q1),rad2deg(q2),rad2deg(q3)];

            app.invp1=pd(1);
            app.invp2=pd(2);
            app.invp3=pd(3);

            send_position_to_motors(app)
            
    
        end
        
        function results = send_position_to_motors(app);
            set_param([app.SimulinkModelName '/motor1_pose'], 'Value', mat2str(round(app.invp1)));
            set_param([app.SimulinkModelName '/motor2_pose'], 'Value', mat2str(round(app.invp2)));
            set_param([app.SimulinkModelName '/motor3_pose'], 'Value', mat2str(round(app.invp3)));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global hApp;
            hApp = app;
            
            disp("I started up")
            app.SimulinkModelName = 'simulation_three_motors';
            app.krak1=0.09;
            app.krak2=0.19;
            app.krak3=0.18;

            trenutni_x = 0;
            trenutni_y = 0;
            trenutni_z = 0;
            

            app.motor1_position=0;
            app.motor2_position=0;
            app.motor3_position=0;
        end

        % Value changed function: Motor1positionSlider
        function Motor1positionSliderValueChanged(app, event)
            value = app.Motor1positionSlider.Value
            app.pp.Value = app.Motor1positionSlider.Value
            app.motor1_position=value;
            app.pp.Value = value;
            value_string = mat2str(round(value));
            app.motor1_position = deg2rad(value)
            direktna_kinematika(app);
            set_param([app.SimulinkModelName '/motor1_pose'], 'Value', value_string);
            drawnow;
        end

        % Value changed function: Motor2positionSlider
        function Motor2positionSliderValueChanged(app, event)
            value = app.Motor2positionSlider.Value;
            app.Trenutnapozicijamotora2EditField.Value = app.Motor2positionSlider.Value
            value_string = mat2str(round(value));
            app.motor2_position = value;
            direktna_kinematika(app);
            set_param([app.SimulinkModelName '/motor2_pose'], 'Value', value_string);
            drawnow;
        end

        % Value changed function: Motor3positionSlider
        function Motor3positionSliderValueChanged(app, event)
            value = app.Motor3positionSlider.Value;
            app.Trenutnapozicijamotora3EditField.Value = value
            value_string = mat2str(round(value));
            app.motor3_position = value;
            direktna_kinematika(app);
            set_param([app.SimulinkModelName '/motor3_pose'], 'Value', value_string);
            drawnow;
        end

        % Value changed function: pp
        function ppValueChanged(app, event)
           app.pp.Value 
           app.pp.Value = round(app.pp.Value);
           app.Motor1positionSlider.Value = app.pp.Value
           value_string = mat2str(app.pp.Value);
           set_param([app.SimulinkModelName '/motor1_pose'], 'Value', value_string);
           app.motor1_position = deg2rad(round(app.pp.Value));
           direktna_kinematika(app);
        end

        % Value changed function: Trenutnapozicijamotora2EditField
        function Trenutnapozicijamotora2EditFieldValueChanged(app, event)
           app.Trenutnapozicijamotora2EditField.Value=round(app.Trenutnapozicijamotora2EditField.Value);
           app.Motor2positionSlider.Value = app.Trenutnapozicijamotora2EditField.Value
           value_string = mat2str(app.Trenutnapozicijamotora2EditField.Value);
           set_param([app.SimulinkModelName '/motor2_pose'], 'Value', value_string);
           app.motor2_position = deg2rad(round(app.Trenutnapozicijamotora2EditField.Value));
           direktna_kinematika(app);
        end

        % Value changed function: Trenutnapozicijamotora3EditField
        function Trenutnapozicijamotora3EditFieldValueChanged(app, event)
           app.Trenutnapozicijamotora3EditField.Value=round(app.Trenutnapozicijamotora3EditField.Value);
           app.Motor3positionSlider.Value = app.Trenutnapozicijamotora3EditField.Value
           value_string = mat2str(app.Trenutnapozicijamotora3EditField.Value);
           set_param([app.SimulinkModelName '/motor3_pose'], 'Value', value_string);      
           app.motor3_position = deg2rad(round(app.Trenutnapozicijamotora3EditField.Value));
           direktna_kinematika(app);
        end

        % Value changed function: XTextArea
        function XTextAreaValueChanged(app, event)
            
        end

        % Value changed function: PostavinaXEditField
        function PostavinaXEditFieldValueChanged(app, event)
            app.invX=round(app.PostavinaXEditField.Value);
            inverzna_kinematika(app);
        end

        % Value changed function: PostavinaYEditField
        function PostavinaYEditFieldValueChanged(app, event)
            app.invY=round(app.PostavinaYEditField.Value);
            inverzna_kinematika(app);
        end

        % Value changed function: PostavinaZEditField
        function PostavinaZEditFieldValueChanged(app, event)
            app.invZ=round(app.PostavinaZEditField.Value);
            inverzna_kinematika(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Motor1positionSliderLabel = uilabel(app.UIFigure);
            app.Motor1positionSliderLabel.Text = 'Motor 1 position';
            app.Motor1positionSlider = uislider(app.UIFigure);
            app.Motor1positionSlider.Limits = [-90 90];
            app.Motor1positionSlider.ValueChangedFcn = createCallbackFcn(app, @Motor1positionSliderValueChanged, true);
            app.Motor2positionSliderLabel = uilabel(app.UIFigure);
            app.Motor2positionSliderLabel.Text = 'Motor 2 position';
            app.Motor2positionSlider = uislider(app.UIFigure);
            app.Motor2positionSlider.Limits = [0 90];
            app.Motor2positionSlider.ValueChangedFcn = createCallbackFcn(app, @Motor2positionSliderValueChanged, true);
            app.Motor3positionSliderLabel = uilabel(app.UIFigure);
            app.Motor3positionSliderLabel.Text = 'Motor 3 position';
            app.Motor3positionSlider = uislider(app.UIFigure);
            app.Motor3positionSlider.Limits = [0 150];
            app.Motor3positionSlider.ValueChangedFcn = createCallbackFcn(app, @Motor3positionSliderValueChanged, true);
            app.Trenutnapozicijamotora1EditFieldLabel = uilabel(app.UIFigure);
            app.Trenutnapozicijamotora1EditFieldLabel.Text = 'Trenutna pozicija motora 1';
            app.pp = uieditfield(app.UIFigure, 'numeric');
            app.pp.ValueChangedFcn = createCallbackFcn(app, @ppValueChanged, true);
            app.Trenutnapozicijamotora2EditFieldLabel = uilabel(app.UIFigure);
            app.Trenutnapozicijamotora2EditFieldLabel.Text = 'Trenutna pozicija motora 2';
            app.Trenutnapozicijamotora2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Trenutnapozicijamotora2EditField.ValueChangedFcn = createCallbackFcn(app, @Trenutnapozicijamotora2EditFieldValueChanged, true);
            app.Trenutnapozicijamotora3EditFieldLabel = uilabel(app.UIFigure);
            app.Trenutnapozicijamotora3EditFieldLabel.Text = 'Trenutna pozicija motora 3';
            app.Trenutnapozicijamotora3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Trenutnapozicijamotora3EditField.ValueChangedFcn = createCallbackFcn(app, @Trenutnapozicijamotora3EditFieldValueChanged, true);
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X';
            app.XTextArea = uitextarea(app.UIFigure);
            app.XTextArea.ValueChangedFcn = createCallbackFcn(app, @XTextAreaValueChanged, true);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y';
            app.YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z';
            app.ZTextArea = uitextarea(app.UIFigure);
            app.PostavinaXEditFieldLabel = uilabel(app.UIFigure);
            app.PostavinaXEditFieldLabel.Text = 'Postavi na X';
            app.PostavinaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostavinaXEditField.ValueChangedFcn = createCallbackFcn(app, @PostavinaXEditFieldValueChanged, true);
            app.PostavinaYEditFieldLabel = uilabel(app.UIFigure);
            app.PostavinaYEditFieldLabel.Text = 'Postavi na Y';
            app.PostavinaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostavinaYEditField.ValueChangedFcn = createCallbackFcn(app, @PostavinaYEditFieldValueChanged, true);
            app.PostavinaZEditFieldLabel = uilabel(app.UIFigure);
            app.PostavinaZEditFieldLabel.Text = 'Postavi na Z';
            app.PostavinaZEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostavinaZEditField.ValueChangedFcn = createCallbackFcn(app, @PostavinaZEditFieldValueChanged, true);
            app.NavedenepozicijemotoradovestemagnetnaLabel = uilabel(app.UIFigure);
            app.NavedenepozicijemotoradovestemagnetnaLabel.Text = 'Navedene pozicije motora dovest će magnet na:';
            app.Zakret1EditFieldLabel = uilabel(app.UIFigure);
            app.Zakret1EditFieldLabel.Text = 'Zakret1';
            app.Zakret1EditField = uieditfield(app.UIFigure, 'text');
            app.Zakret2EditFieldLabel = uilabel(app.UIFigure);
            app.Zakret2EditFieldLabel.Text = 'Zakret2';
            app.Zakret2EditField = uieditfield(app.UIFigure, 'text');
            app.Zakret3EditFieldLabel = uilabel(app.UIFigure);
            app.Zakret3EditFieldLabel.Text = 'Zakret3';
            app.Zakret3EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prarob_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end